classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        EVSlider                      matlab.ui.control.Slider
        EVSliderLabel                 matlab.ui.control.Label
        defaultButton                 matlab.ui.control.StateButton
        SSlider                       matlab.ui.control.Slider
        SSliderLabel                  matlab.ui.control.Label
        FocusButtonGroup              matlab.ui.container.ButtonGroup
        BackgroundButton              matlab.ui.control.RadioButton
        ForegroundButton              matlab.ui.control.RadioButton
        ASlider                       matlab.ui.control.Slider
        ASliderLabel                  matlab.ui.control.Label
        ISOSlider                     matlab.ui.control.Slider
        ISOSliderLabel                matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        RealtimeCameraSimulatorLabel  matlab.ui.control.Label
        PhotoButtonGroup              matlab.ui.container.ButtonGroup
        OptionalButton                matlab.ui.control.RadioButton
        ExampleButton                 matlab.ui.control.RadioButton
        Image                         matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         example_foreground_path = "lounge-hdr-foreground.png"
         example_background_path = "lounge-hdr-background.png"
         photosel;
         focussel;
         ISO = 1100;
         A = 1.8;
         S = 28;
         k = 9;
         default_EV;
    end
    
    
    methods (Access = private)

        %%Process the image to show
        function improcess(app)
            background_path = "";
            foreground_path = "";
            %helpdlg(string(app.BackgroundButton.Value));
            if app.photosel == "Example"
                background_path = app.example_background_path;
                foreground_path = app.example_foreground_path;
            end
            background = imread(background_path);
            foreground = imread(foreground_path);
            [~, ~, alpha] = imread(foreground_path);
            bg_R = background(:, :, 1);
            bg_G = background(:, :, 2);
            bg_B = background(:, :, 3);
            fg_R = foreground(:, :, 1);
            fg_G = foreground(:, :, 2);
            fg_B = foreground(:, :, 3);
            %filter by A 
            forescope = foreground > 0;
            image_size = size(background(:, :, 1));
            image_processed = zeros(image_size(1), image_size(2), 3); 
            filter_size = ceil(log(image_size(1) .* image_size(2) .* app.S)...
                .^ 2.4 ./ 20);%can be change; function to cal the size of filter
            if app.focussel == "Foreground"
                
                %filter the background
%                 bg_R = uint8(filter2(fspecial('average', filter_size), bg_R));
%                 bg_G = uint8(filter2(fspecial('average', filter_size), bg_G));
%                 bg_B = uint8(filter2(fspecial('average', filter_size), bg_B));
                background = imfilter(background, fspecial("average", filter_size), 'replicate');
%                 background = cat(3, bg_R, bg_G, bg_B);
                %cat the back and fore
                se = strel('disk',2);
                image_processed = background.*uint8(~forescope) + foreground;
                image_processed = imclose(image_processed,se);
                
            else 
                alpha(alpha < 255) = 0;
                %filter the background
%                 fg_R = uint8(filter2(fspecial('average', filter_size), fg_R));
%                 fg_G = uint8(filter2(fspecial('average', filter_size), fg_G));
%                 fg_B = uint8(filter2(fspecial('average', filter_size), fg_B));
                foreground = imfilter(foreground, fspecial("average", filter_size), 'replicate');
%                 foreground = cat(3, fg_R, fg_G, fg_B);
                %cat the back and fore
                se = strel('disk',2);
                %image_processed = background.*uint8(~forescope) + foreground;
                image_processed = background.*uint8(~alpha) + foreground .* uint8(~(~(alpha)));
                image_processed = imclose(image_processed,se);
            end

            app.Image.ImageSource = image_processed;
            
        end

        %%calculate EV
        function results = EV(app)
            results = log2(100 .* (app.A .^ 2) / (app.ISO .* app.S));
        end

        %%calculate deltaPIX
        function results = deltaPIX(app)
            results = 256 .* (app.default_EV - EV(app)) ./ app.k;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            DefaultChanged(app, 0);
        end

        % Selection changed function: PhotoButtonGroup
        function PhotoChanged(app, event)
            selectedButton = app.PhotoButtonGroup.SelectedObject;
            
        end

        % Selection changed function: FocusButtonGroup
        function FocusChanged(app, event)
            selectedButton = app.FocusButtonGroup.SelectedObject;
            if selectedButton == app.ForegroundButton
                app.focussel = "Foreground";
                improcess(app);
            else
                app.focussel = "Background";
                improcess(app);
            end
        end

        % Value changed function: defaultButton
        function DefaultChanged(app, event)
            %value = app.defaultButton.Value;
            app.A = 1.8;
            app.ISO = 1100;
            app.S = 1 ./ 28;
            app.default_EV = EV(app);
            app.focussel = "Foreground";
            app.photosel = "Example";
            improcess(app);
            

        end

        % Value changing function: ISOSlider
        function ISOChanging(app, event)
            changingValue = event.Value;
            app.ISO = changingValue;
            %change the EV value
            %app.EVValueLabel.Text = string(round(changingValue));
            app.EVSlider.Value = EV(app);
        end

        % Value changing function: SSlider
        function SChanging(app, event)
            changingValue = event.Value;
            app.S = changingValue;
            app.EVSlider.Value = EV(app);
        end

        % Value changing function: ASlider
        function AChanging(app, event)
            changingValue = event.Value;
            app.A = changingValue;
            app.EVSlider.Value = EV(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ISOSliderLabel = uilabel(app.LeftPanel);
            app.ISOSliderLabel.Text = 'ISO';
            app.ISOSlider = uislider(app.LeftPanel);
            app.ISOSlider.Limits = [100 6400];
            app.ISOSlider.ValueChangingFcn = createCallbackFcn(app, @ISOChanging, true);
            app.ISOSlider.Value = 100;
            app.ASliderLabel = uilabel(app.LeftPanel);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.LeftPanel);
            app.ASlider.Limits = [1.8 32];
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @AChanging, true);
            app.ASlider.Value = 1.8;
            app.FocusButtonGroup = uibuttongroup(app.LeftPanel);
            app.FocusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FocusChanged, true);
            app.FocusButtonGroup.Title = 'Focus';
            app.ForegroundButton = uiradiobutton(app.FocusButtonGroup);
            app.ForegroundButton.Text = 'Foreground';
            app.ForegroundButton.Value = true;
            app.BackgroundButton = uiradiobutton(app.FocusButtonGroup);
            app.BackgroundButton.Text = 'Background';
            app.SSliderLabel = uilabel(app.LeftPanel);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.LeftPanel);
            app.SSlider.Limits = [0.00048828125 2];
            app.SSlider.MajorTicks = [0.00048828125 0.40048828125 0.80048828125 1.20048828125 1.60048828125 2];
            app.SSlider.MajorTickLabels = {'1/2048', '0.4', '0.8', '1.2', '1.6', '2'};
            app.SSlider.ValueChangingFcn = createCallbackFcn(app, @SChanging, true);
            app.SSlider.Value = 0.00048828125;
            app.defaultButton = uibutton(app.LeftPanel, 'state');
            app.defaultButton.ValueChangedFcn = createCallbackFcn(app, @DefaultChanged, true);
            app.defaultButton.Text = 'default';
            app.EVSliderLabel = uilabel(app.LeftPanel);
            app.EVSliderLabel.Text = 'EV';
            app.EVSlider = uislider(app.LeftPanel);
            app.EVSlider.Limits = [-21 21];
            app.EVSlider.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.PhotoButtonGroup = uibuttongroup(app.RightPanel);
            app.PhotoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhotoChanged, true);
            app.PhotoButtonGroup.Title = 'Photo';
            app.ExampleButton = uiradiobutton(app.PhotoButtonGroup);
            app.ExampleButton.Text = 'Example';
            app.ExampleButton.Value = true;
            app.OptionalButton = uiradiobutton(app.PhotoButtonGroup);
            app.OptionalButton.Text = 'Optional';
            app.RealtimeCameraSimulatorLabel = uilabel(app.RightPanel);
            app.RealtimeCameraSimulatorLabel.Text = 'Realtime Camera Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end