classdef DFT_2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TabGroup              matlab.ui.container.TabGroup
        DeltaTab              matlab.ui.container.Tab
        Slider_y              matlab.ui.control.Slider
        Position_yLabel       matlab.ui.control.Label
        Slider_x              matlab.ui.control.Slider
        Position_xLabel       matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        AmplitudeFigureLabel  matlab.ui.control.Label
        PhaseFigureLabel      matlab.ui.control.Label
        OriginFigureLabel     matlab.ui.control.Label
        SineTab               matlab.ui.container.Tab
        PhaseSlider           matlab.ui.control.Slider
        PhaseSliderLabel      matlab.ui.control.Label
        FrequencySlider       matlab.ui.control.Slider
        FrequencySliderLabel  matlab.ui.control.Label
        AngleSlider           matlab.ui.control.Slider
        AngleSliderLabel      matlab.ui.control.Label
        RectangleTab          matlab.ui.container.Tab
        WidthSlider           matlab.ui.control.Slider
        WidthSliderLabel      matlab.ui.control.Label
        LengthSlider          matlab.ui.control.Slider
        LengthSliderLabel     matlab.ui.control.Label
        Center_ySlider        matlab.ui.control.Slider
        Center_ySliderLabel   matlab.ui.control.Label
        Center_xSlider        matlab.ui.control.Slider
        Center_xSliderLabel   matlab.ui.control.Label
        AngleSlider_rec       matlab.ui.control.Slider
        AngleSlider_recLabel  matlab.ui.control.Label
        GaussTab              matlab.ui.container.Tab
        GaborTab              matlab.ui.container.Tab
        Image_phase_3         matlab.ui.control.Image
        Image_ampli_3         matlab.ui.control.Image
        Image_orgin_3         matlab.ui.control.Image
        Image_phase_2         matlab.ui.control.Image
        Image_ampli_2         matlab.ui.control.Image
        Image_origin_2        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Wavetype;
        Origin_fig_2;
        % parameter of Delta function
        position_x;
        position_y;
        % parameter of Sine function
        angle;
        frequency;
        phase;
    end
    
    methods (Access = private)
        
        function Wave_generate(app)
            fig = zeros(256, 256);
            switch app.Wavetype
                case "Delta"
                    fig(257 - app.position_y, app.position_x) = 1;
                case "Sine"
                    an = app.angle .* pi ./ 180;
                    ph = app.phase .* pi ./ 180;
                    [X, Y] = meshgrid(1: 256);
                    fig = cos(2 .* pi .* app.frequency .* (cos(an) .* X + sin(an) .* Y) + ph);
            end
            app.Origin_fig_2 = fig;
        end
        
        function fig_show(app)
            app.Wave_generate();
            app.Image_origin_2.ImageSource = cat(3, app.Origin_fig_2, app.Origin_fig_2, app.Origin_fig_2); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
%             app.Wavetype = "Delta";
%             app.position_x = 128;
%             app.position_y = 128;
%             app.fig_show();
            app.DeltaSelected(0);
        end

        % Button down function: DeltaTab
        function DeltaSelected(app, event)
            app.Wavetype = "Delta";
            app.Slider_x.Value = 128;
            app.Slider_y.Value = 128;
            app.position_x = 128;
            app.position_y = 128;
            app.fig_show();
        end

        % Button down function: SineTab
        function SineSelected(app, event)
            app.Wavetype = "Sine";
            app.AngleSlider.Value = 0;
            app.PhaseSlider.Value = 0;
            app.FrequencySlider.Value = 0.01;
            app.angle = 0;
            app.phase = 0;
            app.frequency = 0.01;
            app.fig_show();

        end

        % Button down function: GaussTab
        function GaussSelected(app, event)
            
        end

        % Button down function: RectangleTab
        function RectangleSelected(app, event)
            
        end

        % Button down function: GaborTab
        function GaborSelected(app, event)
            
        end

        % Value changing function: Slider_x
        function Position_x_Changing(app, event)
            changingValue = event.Value;
            app.position_x = round(changingValue);
            app.fig_show();
        end

        % Value changed function: Slider_x
        function Position_x_Changed(app, event)
            value = app.Slider_x.Value;
            app.position_x = round(value);
            app.fig_show();
        end

        % Value changing function: Slider_y
        function Position_y_Changing(app, event)
            changingValue = event.Value;
            app.position_y = round(changingValue);
            app.fig_show();
        end

        % Value changed function: Slider_y
        function Position_y_Changed(app, event)
            value = app.Slider_y.Value;
            app.position_y = round(value);
            app.fig_show();
        end

        % Value changed function: FrequencySlider
        function Frequency_Changed(app, event)
            value = app.FrequencySlider.Value;
            app.frequency = value;
            app.fig_show();
        end

        % Value changing function: FrequencySlider
        function Frequency_Changing(app, event)
            changingValue = event.Value;
            app.frequency = changingValue;
            app.fig_show();
        end

        % Value changed function: AngleSlider
        function Angle_Changed(app, event)
            value = app.AngleSlider.Value;
            app.angle = value;
            app.fig_show();
        end

        % Value changing function: AngleSlider
        function Angle_Changing(app, event)
            changingValue = event.Value;
            app.angle = changingValue;
            app.fig_show();
        end

        % Value changed function: PhaseSlider
        function Phase_Changed(app, event)
            value = app.PhaseSlider.Value;
            app.phase = value;
            app.fig_show();
        end

        % Value changing function: PhaseSlider
        function Phase_Changing(app, event)
            changingValue = event.Value;
            app.phase = changingValue;
            app.fig_show();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {460, 460};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {240, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DeltaTab = uitab(app.TabGroup);
            app.DeltaTab.Title = 'Delta';
            app.DeltaTab.ButtonDownFcn = createCallbackFcn(app, @DeltaSelected, true);
            app.Position_xLabel = uilabel(app.DeltaTab);
            app.Position_xLabel.Tag = 'PositionGroup';
            app.Position_xLabel.Text = 'Position_x';
            app.Slider_x = uislider(app.DeltaTab);
            app.Slider_x.Limits = [1 256];
            app.Slider_x.MajorTicks = [1 64 128 192 256];
            app.Slider_x.ValueChangedFcn = createCallbackFcn(app, @Position_x_Changed, true);
            app.Slider_x.ValueChangingFcn = createCallbackFcn(app, @Position_x_Changing, true);
            app.Slider_x.MinorTicks = [];
            app.Slider_x.Tag = 'PositionGroup';
            app.Slider_x.Value = 128;
            app.Position_yLabel = uilabel(app.DeltaTab);
            app.Position_yLabel.Tag = 'PositionGroup';
            app.Position_yLabel.Text = 'Position_y';
            app.Slider_y = uislider(app.DeltaTab);
            app.Slider_y.Limits = [1 256];
            app.Slider_y.MajorTicks = [1 64 128 192 256];
            app.Slider_y.ValueChangedFcn = createCallbackFcn(app, @Position_y_Changed, true);
            app.Slider_y.ValueChangingFcn = createCallbackFcn(app, @Position_y_Changing, true);
            app.Slider_y.MinorTicks = [];
            app.Slider_y.Tag = 'PositionGroup';
            app.Slider_y.Value = 128;
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.SineTab.ButtonDownFcn = createCallbackFcn(app, @SineSelected, true);
            app.AngleSliderLabel = uilabel(app.SineTab);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.SineTab);
            app.AngleSlider.Limits = [0 180];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @Angle_Changed, true);
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @Angle_Changing, true);
            app.FrequencySliderLabel = uilabel(app.SineTab);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.SineTab);
            app.FrequencySlider.Limits = [0.002 0.2];
            app.FrequencySlider.MajorTicks = [0.002 0.1 0.2];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @Frequency_Changed, true);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @Frequency_Changing, true);
            app.FrequencySlider.Value = 0.01;
            app.PhaseSliderLabel = uilabel(app.SineTab);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.SineTab);
            app.PhaseSlider.Limits = [0 360];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @Phase_Changed, true);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @Phase_Changing, true);
            app.RectangleTab = uitab(app.TabGroup);
            app.RectangleTab.Title = 'Rectangle';
            app.RectangleTab.ButtonDownFcn = createCallbackFcn(app, @RectangleSelected, true);
            app.AngleSlider_recLabel = uilabel(app.RectangleTab);
            app.AngleSlider_recLabel.Text = 'Angle';
            app.AngleSlider_rec = uislider(app.RectangleTab);
            app.Center_xSliderLabel = uilabel(app.RectangleTab);
            app.Center_xSliderLabel.Text = 'Center_x';
            app.Center_xSlider = uislider(app.RectangleTab);
            app.Center_ySliderLabel = uilabel(app.RectangleTab);
            app.Center_ySliderLabel.Text = 'Center_y';
            app.Center_ySlider = uislider(app.RectangleTab);
            app.LengthSliderLabel = uilabel(app.RectangleTab);
            app.LengthSliderLabel.Text = 'Length';
            app.LengthSlider = uislider(app.RectangleTab);
            app.WidthSliderLabel = uilabel(app.RectangleTab);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.RectangleTab);
            app.GaussTab = uitab(app.TabGroup);
            app.GaussTab.Title = 'Gauss';
            app.GaussTab.ButtonDownFcn = createCallbackFcn(app, @GaussSelected, true);
            app.GaborTab = uitab(app.TabGroup);
            app.GaborTab.Title = 'Gabor';
            app.GaborTab.ButtonDownFcn = createCallbackFcn(app, @GaborSelected, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_origin_2 = uiimage(app.RightPanel);
            app.Image_ampli_2 = uiimage(app.RightPanel);
            app.Image_phase_2 = uiimage(app.RightPanel);
            app.Image_orgin_3 = uiimage(app.RightPanel);
            app.Image_ampli_3 = uiimage(app.RightPanel);
            app.Image_phase_3 = uiimage(app.RightPanel);
            app.OriginFigureLabel = uilabel(app.RightPanel);
            app.OriginFigureLabel.Text = 'Origin Figure';
            app.PhaseFigureLabel = uilabel(app.RightPanel);
            app.PhaseFigureLabel.Text = 'Phase Figure';
            app.AmplitudeFigureLabel = uilabel(app.RightPanel);
            app.AmplitudeFigureLabel.Text = 'Amplitude Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DFT_2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end