classdef HW4_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        SaturationSlider       matlab.ui.control.Slider
        SaturationSliderLabel  matlab.ui.control.Label
        HueSlider              matlab.ui.control.Slider
        HueSliderLabel         matlab.ui.control.Label
        FigureButtonGroup      matlab.ui.container.ButtonGroup
        Pic7Button             matlab.ui.control.RadioButton
        Pic6Button             matlab.ui.control.RadioButton
        Pic5Button             matlab.ui.control.RadioButton
        Pic4Button             matlab.ui.control.RadioButton
        Pic3Button             matlab.ui.control.RadioButton
        Pic2Button             matlab.ui.control.RadioButton
        Pic1Button             matlab.ui.control.RadioButton
        RightPanel             matlab.ui.container.Panel
        Image2                 matlab.ui.control.Image
        Image1                 matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        image_dir
        mask
        sizeroi
        hsvroi
        th
    end
    
    methods (Access = private)
        
        function renew_image(app)
            image = imread(app.image_dir);
            image = rgb2hsv(image);
            [h, w, ~] = size(image);
            image(find(app.mask)) = app.HueSlider.Value ./ 360; %#ok<FNDSB> 
            image(find(app.mask) + h * w) = app.SaturationSlider.Value;
            image_processed = hsv2rgb(image);
            app.Image2.ImageSource = image_processed;
            
        end
        
        function mask = immask(app)
            image = imread(app.image_dir);
            app.Image1.ImageSource = image; % renew the origin pic
            image = rgb2hsv(image);
            [h, w, ~] = size(image);
            sizemask = zeros(h, w);
            sizemask(app.sizeroi(1) : app.sizeroi(2), app.sizeroi(3): app.sizeroi(4)) = 1;
            %figure(1); imshow(sizemask)
            hsvmask = (image(:, :, 1) > app.hsvroi(1)) .* (image(:, :, 1) < app.hsvroi(2));
            %figure(2); imshow(hsvmask)
            hsvmask = hsvmask .* (image(:, :, 2) > app.hsvroi(3)) .* (image(:, :, 2) < app.hsvroi(4));
            %figure(3); imshow(hsvmask)
            hsvmask = hsvmask .* (image(:, :, 3) > app.hsvroi(5)) .* (image(:, :, 3) < app.hsvroi(6));
            %figure(4); imshow(hsvmask)
            app.hsvroi
            mask = sizemask .* hsvmask;
            se = strel('disk', 1);
            mask = bwareaopen(mask, app.th, 8); % 清除噪点
            mask = imdilate(mask, se);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            app.Pic_changed(0);
        end

        % Selection changed function: FigureButtonGroup
        function Pic_changed(app, event)
            selectedButton = app.FigureButtonGroup.SelectedObject;
            % 调整参数
            switch selectedButton
                case app.Pic1Button
                    app.image_dir = "1.jpg";
                    app.sizeroi = [216, 396, 358, 483]; % x1, x2, y1, y2 
                    app.hsvroi = [0.25, 0.6, 0.04, 0.45, 0.4, 0.95];
                    app.th = 200;
                case app.Pic2Button
                    app.image_dir = "2.jpg";
                    app.sizeroi = [448, 679, 141, 397];
                    app.hsvroi = [0.116, 0.18, 0.1, 0.99, 0.2, 1.1];
                    app.th = 200;
                case app.Pic3Button
                    app.image_dir = "3.jpg";
                    app.sizeroi = [105, 329, 113, 287];
                    app.hsvroi = [0.03, 0.1, 0.5, 0.95, 0.2, 1.1];
                    app.th = 200;
                case app.Pic4Button 
                    app.image_dir = "10.jpg";
                    app.sizeroi = [401, 566, 327, 550];
                    app.hsvroi = [-1, 1.1, -1, 0.1, 0.2, 1.1];
                    app.th = 20;
                case app.Pic5Button
                    app.image_dir = "11.jpg";
                    app.sizeroi = [257, 429, 67, 282];
                    app.hsvroi = [0.0, 0.07, 0.1, 0.38, 0.2, 0.69];
                    app.th = 160;
                case app.Pic6Button
                    app.image_dir = "12.jpg";
                    app.sizeroi = [120, 442, 117, 285];
                    app.hsvroi = [0.4, 0.9, 0.1, 0.38, 0.1, 0.9];
                    app.th = 10;
                case app.Pic7Button
                    app.image_dir = "13.jpg";
                    app.sizeroi = [171, 450, 294, 453];
                    app.hsvroi = [0.15, 0.5, 0.3, 1.1, 0.3, 1.1];
                    app.th = 200;
            end
            % 重新生成mask图像
            app.mask = app.immask();
            app.renew_image();
        end

        % Value changing function: HueSlider
        function HChanging(app, event)
            app.renew_image();
        end

        % Value changing function: SaturationSlider
        function SChanging(app, event)
            app.renew_image();
        end

        % Value changed function: HueSlider
        function HChanged(app, event)
            app.renew_image();
        end

        % Value changed function: SaturationSlider
        function SChanged(app, event)
            app.renew_image();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FigureButtonGroup = uibuttongroup(app.LeftPanel);
            app.FigureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Pic_changed, true);
            app.FigureButtonGroup.Title = 'Figure';
            app.Pic1Button = uiradiobutton(app.FigureButtonGroup);
            app.Pic1Button.Text = 'Pic1';
            app.Pic2Button = uiradiobutton(app.FigureButtonGroup);
            app.Pic2Button.Text = 'Pic2';
            app.Pic3Button = uiradiobutton(app.FigureButtonGroup);
            app.Pic3Button.Text = 'Pic3';
            app.Pic4Button = uiradiobutton(app.FigureButtonGroup);
            app.Pic4Button.Text = 'Pic4';
            app.Pic5Button = uiradiobutton(app.FigureButtonGroup);
            app.Pic5Button.Text = 'Pic5';
            app.Pic6Button = uiradiobutton(app.FigureButtonGroup);
            app.Pic6Button.Text = 'Pic6';
            app.Pic7Button = uiradiobutton(app.FigureButtonGroup);
            app.Pic7Button.Text = 'Pic7';
            app.Pic7Button.Value = true;
            app.HueSliderLabel = uilabel(app.LeftPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.LeftPanel);
            app.HueSlider.Limits = [0 360];
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @HChanged, true);
            app.HueSlider.ValueChangingFcn = createCallbackFcn(app, @HChanging, true);
            app.SaturationSliderLabel = uilabel(app.LeftPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.LeftPanel);
            app.SaturationSlider.Limits = [0 1];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SChanged, true);
            app.SaturationSlider.ValueChangingFcn = createCallbackFcn(app, @SChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image1 = uiimage(app.RightPanel);
            app.Image2 = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW4_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end